classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        ButtonSaveResult  matlab.ui.control.Button
        ButtonDetect      matlab.ui.control.Button
        ButtonLoadImage   matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        LegendaPanel      matlab.ui.container.Panel
        PywakLabel        matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        BojaLabel         matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        StatusLamp        matlab.ui.control.Lamp
        Label             matlab.ui.control.Label
        LabelResults      matlab.ui.control.Label
        LabelStatus       matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Net = [];
        CurrentImage = [];
        ImagePath = '';
        DetectionBboxes = [];  % Bounding boxy z ostatniej detekcji
        DetectionLabels = [];  % Etykiety z ostatniej detekcji (1=boja, 0=pływak)
        DetectionNumBuoys = 0; % Liczba boi z ostatniej detekcji
        DetectionNumSwimmers = 0; % Liczba pływaków z ostatniej detekcji
    end
    
    methods (Access = private)
        
        % W main.mlapp (App Designer) -> Code View -> performDetection

    function [bboxes, labels, numBuoys, numSwimmers] = performDetection(app, I)
        % Aplikacja nie liczy sama, tylko zleca to funkcji z folderu src!
        
        % Sprawdzamy czy mamy sieć
        if isempty(app.Net)
            error('Sieć neuronowa nie jest załadowana!');
        end
    
        % Wywołanie logiki z nowego pliku
        [bboxes, labels, numBuoys, numSwimmers] = detect_objects_logic(I, app.Net);
    end

    function displayResults(app, I, bboxes, labels)
    % 1. Wyświetl czyste zdjęcie
    imshow(I, 'Parent', app.UIAxes);
    
    % 2. Wywołaj funkcję z SRC, podając app.UIAxes jako cel
    visualize_results(app.UIAxes, bboxes, labels);
    
    % 3. Aktualizuj tytuł (statystyki)
    numBuoys = sum(labels == 1);
    numSwimmers = sum(labels == 0);
    [~, fn, ~] = fileparts(app.ImagePath);
    app.UIAxes.Title.String = sprintf('%s - Boje: %d, Ludzie: %d', fn, numBuoys, numSwimmers);
end
        
        % HELPER FUNCTION: Save Results (opcjonalnie)
        
        function saveResults(app, bboxes, labels, numBuoys, numSwimmers)
    % Zapisuje wyniki do plików, używając wspólnej logiki rysowania
    
    % 1. Sprawdzenie i utworzenie folderów (bez zmian)
    if ~exist('results', 'dir')
        mkdir('results');
    end

    figDir = fullfile('results', 'figures');
    logDir = fullfile('results', 'logs');

    if ~exist(figDir, 'dir')
        mkdir(figDir);
    end
    if ~exist(logDir, 'dir')
        mkdir(logDir);
    end

    % 2. Generowanie nazwy pliku (bez zmian)
    [~, baseName, ~] = fileparts(app.ImagePath);
    ts = char(datetime('now', 'Format', 'yyyyMMdd_HHmmss'));
    detFigName = sprintf('%s_detection_%s.png', baseName, ts);
    detFigPath = fullfile(figDir, detFigName);

    % 3. TWORZENIE OBRAZKA (Refaktoryzacja)
    % Tworzymy tymczasową, niewidoczną figurę
    fig = figure('Visible', 'off');
    ax = axes(fig);
    
    % Wyświetlamy czyste zdjęcie
    imshow(app.CurrentImage, 'Parent', ax);
    
    % --- KLUCZOWA ZMIANA ---
    % Zamiast pisać tu pętlę for, wywołujemy naszą funkcję z src.
    % Przekazujemy jej 'ax' (oś tymczasowej figury), żeby wiedziała gdzie rysować.
    visualize_results(ax, bboxes, labels);
    % -----------------------

    % Dodajemy tytuł statystyk
    title(ax, sprintf('Boje: %d   Ludzie: %d', numBuoys, numSwimmers));

    % 4. Zapis do pliku graficznego
    saveas(fig, detFigPath);
    close(fig); % Ważne: zamykamy figurę, żeby nie zjadała RAMu

    % 5. Zapis logów tekstowych (bez zmian)
    logPath = fullfile(logDir, sprintf('detect_log_%s.txt', ts));
    fid = fopen(logPath, 'w');
    if fid ~= -1
        dtStr = char(datetime('now'));
        fprintf(fid, 'Data: %s\n', dtStr);
        fprintf(fid, 'Plik obrazu: %s\n', app.ImagePath);
        fprintf(fid, 'Boje: %d\n', numBuoys);
        fprintf(fid, 'Ludzie: %d\n', numSwimmers);
        fclose(fid);
    end
    
    % Opcjonalnie: komunikat w konsoli dla debugowania
    disp(['Zapisano wynik w: ' detFigPath]);
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Wymuś otwarcie okna na pełnym ekranie
            app.UIFigure.WindowState = 'maximized';
            try
                
                modelPath = fullfile('models', 'net_buoy.mat');
                if exist(modelPath, 'file')
                    data = load(modelPath, 'net');
                    app.Net = data.net;
                    app.LabelStatus.Text = 'Status: Model załadowany pomyślnie';
                    app.LabelStatus.FontColor = [0 0.6 0];
                else
                    app.LabelStatus.Text = 'Status: BŁĄD - Nie znaleziono modelu!';
                    app.LabelStatus.FontColor = [1 0 0];
                    uialert(app.UIFigure, ...
                        sprintf('Nie znaleziono pliku modelu:\n%s\n\nUpewnij się, że model został wytrenowany.', modelPath), ...
                        'Błąd ładowania modelu', 'Icon', 'error');
                end
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas ładowania modelu:\n%s', ME.message), ...
                    'Błąd', 'Icon', 'error');
            end
            % W startupFcn:
            axis(app.UIAxes, 'off'); % Ukryj osie na start
            % Dopiero po wczytaniu zdjęcia w ButtonLoadImagePushed robisz axis(app.UIAxes, 'image');
        end

        % Button pushed function: ButtonLoadImage
        function ButtonLoadImagePushed(app, event)
            [fn, fp] = uigetfile({'*.jpg;*.png;*.jpeg', 'Obrazy (*.jpg, *.png, *.jpeg)'; ...
                                   '*.*', 'Wszystkie pliki (*.*)'}, ...
                                  'Wybierz obraz do analizy');
        
            if isequal(fn, 0)
                % Użytkownik anulował wybór
                return;
            end
        
            try
                % Wczytaj obraz
                imgPath = fullfile(fp, fn);
                I = imread(imgPath);
        
                % Zapisz obraz i ścieżkę
                app.CurrentImage = I;
                app.ImagePath = imgPath;
        
                % Wyświetl obraz w osiach
                imshow(I, 'Parent', app.UIAxes);
                app.UIAxes.Title.String = fn;
        
                % Aktualizuj status
                app.LabelStatus.Text = sprintf('Status: Obraz wczytany - %s', fn);
                app.LabelStatus.FontColor = [0 0.6 0];
        
                % Wyczyść poprzednie wyniki
                app.LabelResults.Text = 'Wyniki: -';
                app.DetectionBboxes = [];
                app.DetectionLabels = [];
                app.DetectionNumBuoys = 0;
                app.DetectionNumSwimmers = 0;
        
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas wczytywania obrazu:\n%s', ME.message), ...
                    'Błąd', 'Icon', 'error');
            end
        end

        % Button pushed function: ButtonDetect
        function ButtonDetectPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, ...
                    'Najpierw wczytaj obraz!', ...
                    'Brak obrazu', 'Icon', 'warning');
                return;
            end
        
            % Sprawdź czy model został załadowany
            if isempty(app.Net)
                uialert(app.UIFigure, ...
                    'Model nie został załadowany!', ...
                    'Brak modelu', 'Icon', 'error');
                return;
            end
        
            try
                app.LabelStatus.Text = 'Status: Wykonywanie detekcji...';
                app.StatusLamp.Color = 'yellow';
                app.LabelStatus.FontColor = [1 0.65 0];
                drawnow; % Aktualizuj interfejs
        
                % Wykonaj detekcję
                [bboxes, labels, numBuoys, numSwimmers] = performDetection(app, app.CurrentImage);
        
                % Wyświetl wyniki na obrazie
                displayResults(app, app.CurrentImage, bboxes, labels);
        
                % Aktualizuj status i wyniki
                app.LabelStatus.Text = 'Status: Detekcja zakończona';
                app.LabelStatus.FontColor = [0 0.6 0];
                app.LabelResults.Text = sprintf('Wyniki: Boje: %d | Ludzie: %d', numBuoys, numSwimmers);
                app.StatusLamp.Color = 'green';

                % Zapisz wyniki detekcji do properties (do późniejszego zapisu)
                app.DetectionBboxes = bboxes;
                app.DetectionLabels = labels;
                app.DetectionNumBuoys = numBuoys;
                app.DetectionNumSwimmers = numSwimmers;
        
            catch ME
                app.StatusLamp.Color = 'red';
                app.LabelStatus.Text = sprintf('Status: BŁĄD - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas detekcji:\n%s', ME.message), ...
                    'Błąd detekcji', 'Icon', 'error');
            end
        end

        % Button pushed function: ButtonSaveResult
        function ButtonSaveResultPushed(app, event)
            if isempty(app.DetectionBboxes) || isempty(app.DetectionLabels)
                uialert(app.UIFigure, ...
                    'Brak wyników detekcji do zapisania!\nNajpierw wykonaj detekcję.', ...
                    'Brak wyników', 'Icon', 'warning');
                return;
            end
        
            % Sprawdź czy obraz jest dostępny
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, ...
                    'Brak obrazu do zapisania!', ...
                    'Brak obrazu', 'Icon', 'warning');
                return;
            end
        
            try
                app.LabelStatus.Text = 'Status: Zapisywanie wyników...';
                app.LabelStatus.FontColor = [1 0.65 0];
                drawnow;
        
                % Zapisz wyniki
                saveResults(app, app.DetectionBboxes, app.DetectionLabels, ...
                            app.DetectionNumBuoys, app.DetectionNumSwimmers);
        
                % Aktualizuj status
                app.LabelStatus.Text = 'Status: Wyniki zapisane pomyślnie';
                app.LabelStatus.FontColor = [0 0.6 0];
        
                uialert(app.UIFigure, ...
                    'Wyniki zostały zapisane w folderze results/', ...
                    'Zapis zakończony', 'Icon', 'success');
        
            catch ME
                app.LabelStatus.Text = sprintf('Status: BŁĄD zapisu - %s', ME.message);
                app.LabelStatus.FontColor = [1 0 0];
                uialert(app.UIFigure, ...
                    sprintf('Błąd podczas zapisywania wyników:\n%s', ME.message), ...
                    'Błąd zapisu', 'Icon', 'error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonLoadImage = uibutton(app.LeftPanel, 'push');
            app.ButtonLoadImage.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadImagePushed, true);
            app.ButtonLoadImage.Text = 'Wczytaj';
            app.ButtonDetect = uibutton(app.LeftPanel, 'push');
            app.ButtonDetect.ButtonPushedFcn = createCallbackFcn(app, @ButtonDetectPushed, true);
            app.ButtonDetect.Text = 'Wykryj';
            app.ButtonSaveResult = uibutton(app.LeftPanel, 'push');
            app.ButtonSaveResult.ButtonPushedFcn = createCallbackFcn(app, @ButtonSaveResultPushed, true);
            app.ButtonSaveResult.Text = 'Zapisz';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LabelStatus = uilabel(app.RightPanel);
            app.LabelStatus.Text = 'Status';
            app.LabelResults = uilabel(app.RightPanel);
            app.LabelResults.Text = 'Wyniki';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.StatusLamp = uilamp(app.RightPanel);
            app.LegendaPanel = uipanel(app.RightPanel);
            app.LegendaPanel.Title = 'Legenda';
            app.Label_4 = uilabel(app.LegendaPanel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.LegendaPanel);
            app.Label_5.Text = '';
            app.Label_2 = uilabel(app.LegendaPanel);
            app.Label_2.Text = '';
            app.BojaLabel = uilabel(app.LegendaPanel);
            app.BojaLabel.Text = 'Boja';
            app.Label_3 = uilabel(app.LegendaPanel);
            app.Label_3.Text = '';
            app.PywakLabel = uilabel(app.LegendaPanel);
            app.PywakLabel.Text = 'Pływak';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end